classdef terminal_assignment_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Thu_Tm_08TerminalAssignmentGUIUIFigure  matlab.ui.Figure
        FileNameTextAreaLabel        matlab.ui.control.Label
        FileNameTextArea             matlab.ui.control.TextArea
        CostTableView                matlab.ui.control.Table
        IterationsGaugeLabel         matlab.ui.control.Label
        IterationsGauge              matlab.ui.control.Gauge
        IterationVsCost              matlab.ui.control.UIAxes
        ConnectionConfiguration      matlab.ui.control.UIAxes
        ConnectionsPerConcentrator   matlab.ui.control.UIAxes
        EpochSizeEditFieldLabel      matlab.ui.control.Label
        EpochSizeEditField           matlab.ui.control.NumericEditField
        FinalEditField               matlab.ui.control.NumericEditField
        STARTButton                  matlab.ui.control.Button
        STOPButton                   matlab.ui.control.Button
        RunningLamp                  matlab.ui.control.Lamp
        LearningRateEditFieldLabel   matlab.ui.control.Label
        LearningRateEditField        matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        MinimumCostGaugeLabel        matlab.ui.control.Label
        MinimumCostGauge             matlab.ui.control.Gauge
        TotalEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        % The amount by which the prob vector changes each epoch
        learning_rate = 0.01;
        % The number of trials in an epoch
        epoch_size = 100;
        % The max number of epochs to find a solution
        max_iterations = 1000;
        % Filename of the cost table
        filename = "CostTable.xlsx";
        % Number of concentrators (in cost table)
        num_c = 0;
        % Number of terminals (in cost table)
        num_t = 0;
        % Max bits required to store each terminal's possible connection
        entry_size = 0;
        % Length of a solution (num_t * entry_size)
        sol_length = 0; 
        % Cost table used to determine connection costs
        cost_table;
        % Probability vector to find the optimal solution
        prob_vector;
        % Determines whether or not to continue running the program
        is_running = false;
    end
    
    methods (Access = private)
        
        function reset(app)
            % reset clears the GUI & plots
            % Resets fields and gauges
            app.IterationsGauge.Value = 0;
            app.MinimumCostGauge.Value = 0;
            app.FinalEditField.Value = 0;
            app.TotalEditField.Value = 0;
            % Resets plots
            cla(app.IterationVsCost);
            cla(app.ConnectionConfiguration);
            cla(app.ConnectionsPerConcentrator);       
        end
        
        function updatePV(app, best_solution)
            % updatePV takes in solution and updates probability vector
            % Modifies probability vector, increasing chance of getting 1
            % or 0 if solution has 1 or 0, respectively
            app.prob_vector(best_solution==1) ...
            = app.prob_vector(best_solution==1) + app.learning_rate;
            app.prob_vector(best_solution==0) ...
            = app.prob_vector(best_solution==0) - app.learning_rate;
            % Entries over 1 or below 0 are rounded to 1 or 0 respectively
            app.prob_vector(app.prob_vector>1) = 1;
            app.prob_vector(app.prob_vector<0) = 0;
        end
            
        function valid = isValid(app, solution)
            %isValid takes in a binary string and returns true if it adheres to constraints
            % Reshapes the solution into the separate terminals
            solution = reshapeSolution(app, solution);
            % Keeps count of each concentrator's connections
            concentrator_connections = zeros(app.num_c, 1);
            valid = true;
            % Loops over each terminal, and checks if the connected concentrator is 
            % used more than 3 times; if so, the solution is invalid
            for i = 1 : app.num_t
                % % Gets the concentrator and converts it to a number
                concentrator = convertToInt(app, solution(:, i)) + 1;
                concentrator_connections(concentrator) = concentrator_connections(concentrator) + 1;
                if (concentrator_connections(concentrator) > 3)
                    valid = false;
                    break
                end
            end
        end
            
        function reached = solutionReached(~, cost_array, iteration)
            % isSolutionReached checks if a solution has been reached, and
            % returns true if reached
            % If std dev for last 10 iterations is 0, assume solution is reached
            reached = false;
            if (iteration > 10)
                reached = std(cost_array(iteration-10:iteration))==0;
            end
        end
        
        function total_cost = getTotalCost(app, solution)
            %getCost takes in a binary string and returns the total cost of the solution
            % Reshapes the solution into the separate terminals
            sol = reshapeSolution(app, solution);
            total_cost = 0;
            % Loops over each terminal and sums the cost of connections between 
            % terminals and their corresponding concentrators
            for terminal = 1 : app.num_t
                concentrator = convertToInt(app, sol(:, terminal)) + 1;
                % If the concentrator is larger than expected, break
                if (concentrator > app.num_c)
                   total_cost = inf;
                   break;
                end
                total_cost = total_cost + app.cost_table(concentrator, terminal);
            end
        end
        
        function number = convertToInt(app, binary_array)
            % convertToInt converts an array of 1s and 0s to a base 10
            % number and returns it
            % Uses bitshift instead of 2^(power) -> efficiency
            number = 0;
            for index = 1 : app.entry_size
                number = number + binary_array(index)*bitshift(1, app.entry_size-index);
            end
        end
        
        function new_solution = reshapeSolution(app, solution)
            % Reshapes solution into separate terminals and returns it
            new_solution = reshape(solution, [app.entry_size, app.num_t]);
        end
        
        function new_cost_table = getCostTable(app)
            % If the file doesn't exist, return
            if exist(app.filename, "file") == 0
                 return;
            end
            % Cost table matrix where rows are concentrators and cols are terminals
            % Gets cost table and removes the NaN rows and columns
            new_cost_table = readmatrix(app.filename);
            [~, cols] = size(new_cost_table);
            % Stores cols that are entirely NaNs (i.e. the headers)
            nan_cols = [];
            % Bool to check if there's a single NaN in a row/column (means that 
            % the specific row & col are part of the headers
            nan_row_col = false;
            for i = 1 : cols
                if (isnan(new_cost_table(:,i)))
                    nan_cols(end+1) = i;
            elseif (isnan(new_cost_table(1,i)))
                    nan_row_col = true;
                end
            end
            % Deletes the columns full of NaNs
            new_cost_table(:,nan_cols) = [];
            % Deletes the row & column with the singular NaN (it's always in the
            % first index for row & col)
            if (nan_row_col)
                new_cost_table(:,1) = [];
                new_cost_table(1,:) = [];
            end
            % The cost table without headers
            app.cost_table = new_cost_table;
            % Number of concentrators and terminals
            [app.num_c, app.num_t] = size(app.cost_table);
            % Dynamically sets row & column names for the cost table view
            app.CostTableView.ColumnName = "Terminal " + [1:app.num_t];
            app.CostTableView.RowName = "Concentrator " + [1:app.num_c];
        end
    
        function plotData(app, min_costs)
            % Reshapes the probability vector into the separate terminals
            PV = reshapeSolution(app, app.prob_vector);
            % Stores how many connections each concentrator has
            concentrator_connections = zeros(1, app.num_c);
            % Stores which terminals are connected to which concentrators
            terminal_connections = zeros(1, app.num_t);
           
            % For each terminal, gets & stores the connected concentrator
            % as a number and increments the concentrator's connection count by 1
            for terminal = 1 : app.num_t
                concentrator = convertToInt(app, PV(:, terminal)) + 1;
                concentrator_connections(concentrator) = concentrator_connections(concentrator) + 1;
                terminal_connections(terminal) = concentrator;
            end
            
            % Plots iterations vs min cost
            plot(app.IterationVsCost, min_costs);
            % Plots the connection configuration (which terminal is
            % connected to which concentrator)
            app.ConnectionConfiguration.YLim = [1 app.num_c];
            app.ConnectionConfiguration.XLim = [1 app.num_t];
            scatter(app.ConnectionConfiguration, [1:app.num_t], terminal_connections, 50, 'filled');
            % Plots the number of connections per concentrator
            bar(app.ConnectionsPerConcentrator, concentrator_connections);
        end
        
        function enableButtons(app)
           % enableButtons enables buttons and field editability based on
           % whether the app is running or not
           app.EpochSizeEditField.Editable = ~app.is_running;
           app.MaxIterationsEditField.Editable = ~app.is_running;
           app.LearningRateEditField.Editable = ~app.is_running;
           app.FileNameTextArea.Editable = ~app.is_running;
           app.STARTButton.Enable = ~app.is_running;
           app.STOPButton.Enable = app.is_running;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FileNameTextArea
        function FileNameTextAreaValueChanged(app, event)
            % Updates filename when changed
            app.filename = char(app.FileNameTextArea.Value);
        end

        % Value changed function: EpochSizeEditField
        function EpochSizeValueChanged(app, event)
            % Updates epoch size  when changed
            app.epoch_size = app.EpochSizeEditField.Value;
        end

        % Value changed function: LearningRateEditField
        function LearningRateValueChanged(app, event)
            % Updates learning rate when changed
            app.learning_rate = app.LearningRateEditField.Value;
        end

        % Value changed function: MaxIterationsEditField
        function MaxIterationsValueChanged(app, event)
            % Updates max iterations rate when changed
            app.max_iterations = app.MaxIterationsEditField.Value;
            % Sets limits of iterations gauge based on max iterations
            app.IterationsGauge.Limits = [0 app.max_iterations];
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % Resets the GUI
            reset(app);
            app.is_running = true;
            % Disables start button, enables stop button and makes fields
            % uneditable
            enableButtons(app);
            % Gets cost table - if not successful, show error and return
            getCostTable(app);
            if app.num_c == 0 || app.num_t == 0
                msgbox('Please ensure a valid cost table is in the same directory');
                app.RunningLamp.Color = 'red';
                return; 
            end
            
            app.CostTableView.Data = app.cost_table;
            
            % Set lamp to green, indicating program running
            app.RunningLamp.Color = 'green';
            drawnow;
            % Max bits to store the range of terminals
            app.entry_size = length(dec2bin(app.num_c-1));
            % Length of each solution
            app.sol_length = app.num_t*app.entry_size;
            
            % The initial probability vector
            app.prob_vector = ones(1, app.sol_length) * 0.5;
            
            % Keeps track of the min cost of each epoch
            min_costs = zeros(1, app.max_iterations);
            % Keeps track of the number of iterations run
            iterations = 0;
            
            % Runs until solution is reached or max # of iterations reached
            % If stop is pressed, the loop ends
            while (iterations < app.max_iterations && ~solutionReached(app, min_costs, iterations) && app.is_running)
                
                iterations = iterations + 1;
                % Keeps track of the min_cost of this epoch/iteration
                min_cost = inf;
                
                for trial = 1 : app.epoch_size
                    % Generates a solution based on the probability vector
                    current_solution = rand(1, app.sol_length) < app.prob_vector;
                    current_cost = getTotalCost(app, current_solution);
                    
                    % If it's cost is less than current cost & it's valid,
                    % reassign min_cost and best_solution
                    if (min_cost > current_cost && isValid(app, current_solution))
                        min_cost = current_cost;
                        best_solution = current_solution;
                    end
                end
                
                % Add this epoch's/iteration's min cost to the array
                min_costs(iterations) = min_cost;
                % Use the best solution to alter the probability vector
                updatePV(app, best_solution);
                % Update the Gauges & Fields displaying iterations & min cost
                app.IterationsGauge.Value = iterations;
                app.MinimumCostGauge.Value = min_cost;
                app.TotalEditField.Value = iterations;
                app.FinalEditField.Value = min_cost;
                drawnow;
            end
            
            % Removes any 0 elements from min_costs as these do not need to be graphed
            min_costs = min_costs(min_costs~=0);
            
            % Rounds the probability vector if not already 1s and 0s
            app.prob_vector = round(app.prob_vector);
            % Plots the relevant data
            plotData(app, min_costs);
            % Turn off running light
            app.RunningLamp.Color = 'white';
            
            app.is_running = false;
            
            % Enables start button & fields are editable again
            enableButtons(app);
            drawnow;
                            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % Stops the main loop when pressed
            app.RunningLamp.Color = 'white';
            app.is_running = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Thu_Tm_08TerminalAssignmentGUIUIFigure = uifigure('Visible', 'off');
            app.Thu_Tm_08TerminalAssignmentGUIUIFigure.Name = 'Thu_Tm_08: Terminal Assignment GUI';
            app.FileNameTextAreaLabel = uilabel(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.FileNameTextAreaLabel.Text = {'File Name '; ''};
            app.FileNameTextArea = uitextarea(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.FileNameTextArea.ValueChangedFcn = createCallbackFcn(app, @FileNameTextAreaValueChanged, true);
            app.FileNameTextArea.Value = {'CostTable.xlsx'};
            app.CostTableView = uitable(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.CostTableView.ColumnName = {'Terminal 1'; 'Terminal 2'; 'Terminal 3'; 'Terminal 4'; 'Terminal 5'; 'Terminal 6'; ''};
            app.CostTableView.RowName = {};
            app.IterationsGaugeLabel = uilabel(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.IterationsGaugeLabel.Text = 'Iterations';
            app.IterationsGauge = uigauge(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'circular');
            app.IterationsGauge.Limits = [0 1000];
            app.IterationVsCost = uiaxes(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            title(app.IterationVsCost, 'Iteration vs Minimum Cost')
            xlabel(app.IterationVsCost, 'Iteration')
            ylabel(app.IterationVsCost, 'Minimum Cost')
            app.ConnectionConfiguration = uiaxes(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            title(app.ConnectionConfiguration, 'Connection Configuration')
            xlabel(app.ConnectionConfiguration, 'Terminal')
            ylabel(app.ConnectionConfiguration, 'Concentrator ')
            app.ConnectionConfiguration.XLim = [0 100];
            app.ConnectionConfiguration.YLim = [0 100];
            app.ConnectionConfiguration.ZLim = [0 1];
            app.ConnectionsPerConcentrator = uiaxes(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            title(app.ConnectionsPerConcentrator, 'Connections per Concentrator')
            xlabel(app.ConnectionsPerConcentrator, 'Concentrator')
            ylabel(app.ConnectionsPerConcentrator, 'Connections')
            app.EpochSizeEditFieldLabel = uilabel(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.EpochSizeEditFieldLabel.Text = 'Epoch Size';
            app.EpochSizeEditField = uieditfield(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'numeric');
            app.EpochSizeEditField.Limits = [1 1000000000];
            app.EpochSizeEditField.RoundFractionalValues = 'on';
            app.EpochSizeEditField.ValueChangedFcn = createCallbackFcn(app, @EpochSizeValueChanged, true);
            app.EpochSizeEditField.Value = 100;
            app.FinalEditField = uieditfield(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'numeric');
            app.FinalEditField.Editable = 'off';
            app.STARTButton = uibutton(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.RunningLamp = uilamp(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.LearningRateEditFieldLabel = uilabel(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'numeric');
            app.LearningRateEditField.Limits = [1e-05 0.99999];
            app.LearningRateEditField.ValueChangedFcn = createCallbackFcn(app, @LearningRateValueChanged, true);
            app.LearningRateEditField.Value = 0.01;
            app.MaxIterationsEditFieldLabel = uilabel(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'numeric');
            app.MaxIterationsEditField.Limits = [1 1000000];
            app.MaxIterationsEditField.RoundFractionalValues = 'on';
            app.MaxIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterationsValueChanged, true);
            app.MaxIterationsEditField.Value = 1000;
            app.MinimumCostGaugeLabel = uilabel(app.Thu_Tm_08TerminalAssignmentGUIUIFigure);
            app.MinimumCostGaugeLabel.Text = 'Minimum Cost';
            app.MinimumCostGauge = uigauge(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'circular');
            app.MinimumCostGauge.Limits = [0 250];
            app.MinimumCostGauge.MajorTicks = [0 50 100 150 200 250 300];
            app.TotalEditField = uieditfield(app.Thu_Tm_08TerminalAssignmentGUIUIFigure, 'numeric');
            app.TotalEditField.Editable = 'off';
            % Show the figure after all components are created
            app.Thu_Tm_08TerminalAssignmentGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = terminal_assignment_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Thu_Tm_08TerminalAssignmentGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Thu_Tm_08TerminalAssignmentGUIUIFigure)
        end
    end
end